classdef segmentationOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SegmentationOptionsLabel       matlab.ui.control.Label
        PretrainedModelEditFieldLabel  matlab.ui.control.Label
        PretrainedModelEditField       matlab.ui.control.EditField
        SelectmodelButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        mainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;
            if isempty(mainApp.comet_handles.segmentationOptions.modelPath) || strcmp(mainApp.comet_handles.segmentationOptions.modelPath,'Default')
                app.PretrainedModelEditField.Value = 'Default';
            else
                app.PretrainedModelEditField.Value = app.mainApp.comet_handles.segmentationOptions.modelPath;
            end
        end

        % Button pushed function: SelectmodelButton
        function SelectmodelButtonPushed(app, event)
            [fileName, path] = uigetfile('Select a pre-trained model!');
            if ischar(path)
                app.PretrainedModelEditField.Value = fullfile(path,fileName);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.comet_handles.segmentationOptions.modelPath = app.PretrainedModelEditField.Value;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SegmentationOptionsLabel = uilabel(app.UIFigure);
            app.SegmentationOptionsLabel.Text = 'Segmentation Options';
            app.PretrainedModelEditFieldLabel = uilabel(app.UIFigure);
            app.PretrainedModelEditFieldLabel.Text = 'Pre-trained Model';
            app.PretrainedModelEditField = uieditfield(app.UIFigure, 'text');
            app.PretrainedModelEditField.Value = 'Default';
            app.SelectmodelButton = uibutton(app.UIFigure, 'push');
            app.SelectmodelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmodelButtonPushed, true);
            app.SelectmodelButton.Text = 'Select model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentationOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end